classdef zoom_def < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScalingUIFigure  matlab.ui.Figure
        LoadimageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        ZoomButton       matlab.ui.control.Button
        Rotate90Button   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            % Carico un'immagine dal disco 
            %disableDefaultInteractivity(app.UIAxes) %disabilita l'interattività del grafico che mostra l'immagine caricata
            [filename, filepath] = uigetfile({'*.jpg';'*.png';'*.bmp'}, "immagine da caricare"); %permette di selezionare solo file con le estensioni specificate
            fullname = [filepath filename]; %carico le informazioni necessarie per leggere l'immagine
            try
                %lettura immagine e stampa sul grafico
                RGBimage = imread(fullname); %legge l'immagine caricata in fullname
                imshow(RGBimage,'Parent',app.UIAxes) %stampa immagine
                %abilitazione degli altri buttons
                app.ZoomButton.Visible=true;
                app.Rotate90Button.Visible=true;
            catch
                if isequal(filename,0)
                    msgbox('Immagine non selezionata','Warning','warn')
                end
                
            end
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
            RGBimage=getimage(app.UIAxes); %preleva l'immagine dal grafico della GUI
            RGBimage=imrotate(RGBimage,90); %ruota a sx di 90°
            imshow(RGBimage,'Parent',app.UIAxes) %stampa della nuova immagine ruotata
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
                RGBimage=getimage(app.UIAxes);
                
                figure(1)
                imshow(RGBimage) %apertura di una nuova finestra per effettuare lo zoom
                title('Immagine Originale')
                uiwait(msgbox('Selezionare parte da ingrandire','Help','help'))
                sp=[];
                
                    condition=true;
                    while condition
                        sp=getrect; %selezione riquadro da zoommare
                        condition=~isempty(find(sp<0,1));
                        if condition
                            uiwait(msgbox('Errore selezione','Error','error')) %errore da visualizzare nel caso in cui il rettangolo selezionato non è interamente contenuto nell'immagine
                        end
                    end
                    close(figure(1))
                    %Caricamento in un vettore gli estremi del riquadro
                    sp(1) = max(floor(sp(1)), 1); %xmin
                    sp(2) = max(floor(sp(2)), 1);%ymin
                    sp(3)= min(ceil(sp(1) + sp(3))); %xmax
                    sp(4)=min(ceil(sp(2) +sp(4))); %ymax
                    
                    %creazione nuova immagine usando i 4 vertici
                    zoomIm=RGBimage(sp(2):sp(4), sp(1): sp(3),:); %essendo a colori bisgona selezionare 3 rettangoli
                    interp(zoomIm)
                    %imshow(zoomIm,'Parent',app.UIAxes4) %stampa del rettangolo selezionato
                    
                    %aumento risoluzione dell'immagine zoommata
                    %[rows, columns, dim] = size(zoomIm);
                    %[X,Y] = meshgrid(1:columns,1:rows);
                    %xi = 1:0.02:columns; 
                    %yi = 1:0.02:rows;  
                    %[Xi,Yi] = meshgrid(xi,yi);
                                         
                    %interpolazione usando le spline
                    %for i=1:dim
                    %    resizedImage(:,:,i) = uint8(interp2(X,Y,double(zoomIm(:,:,i)),Xi,Yi,'spline'));
                    %end
                    
                    %stampa immagine a risoluzione aumentata
                    %imshow(resizedImage,'Parent',app.UIAxes5)
                    
                    %disegno punti dell'immagine originale in scala grigi
                    %app.UIAxes2.Visible=true;
                    %mesh(app.UIAxes2,X,Y,rgb2gray(zoomIm))
                    %disegno punti dell'immagine con risoluzione aumentata in scala grigi
                    %app.UIAxes3.Visible=true;
                    %mesh(app.UIAxes3,Xi,Yi,rgb2gray(resizedImage))
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScalingUIFigure = uifigure;
            app.ScalingUIFigure.Name = 'Scaling';
            app.LoadimageButton = uibutton(app.ScalingUIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Icon = 'load.png';
            app.LoadimageButton.Text = 'Load image';
            app.UIAxes = uiaxes(app.ScalingUIFigure);
            title(app.UIAxes, 'Immagine caricata')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.ZoomButton = uibutton(app.ScalingUIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Icon = 'zoom.png';
            app.ZoomButton.Visible = 'off';
            app.ZoomButton.Text = 'Zoom';
            app.Rotate90Button = uibutton(app.ScalingUIFigure, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Icon = 'rotate.png';
            app.Rotate90Button.Visible = 'off';
            app.Rotate90Button.Text = 'Rotate90°';
        end
    end

    methods (Access = public)

        % Construct app
        function app = zoom_def

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScalingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScalingUIFigure)
        end
    end
end